classdef BeamApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MomentofInertiaEditFieldLabel_2  matlab.ui.control.Label
        MomentofInertiaEditField       matlab.ui.control.NumericEditField
        MomentofInertiaEditFieldLabel  matlab.ui.control.Label
        LoadLocationinSlider           matlab.ui.control.Slider
        LoadLocationinSliderLabel      matlab.ui.control.Label
        LoadpoundsSlider               matlab.ui.control.Slider
        LoadpoundsSliderLabel          matlab.ui.control.Label
        FlangeThicknessinSlider        matlab.ui.control.Slider
        FlangeThicknessinSliderLabel   matlab.ui.control.Label
        WebThicknessinSlider           matlab.ui.control.Slider
        WebThicknessinSliderLabel      matlab.ui.control.Label
        BeamWidthinSlider              matlab.ui.control.Slider
        BeamWidthinSliderLabel         matlab.ui.control.Label
        BeamHeightinSlider             matlab.ui.control.Slider
        BeamHeightinSliderLabel        matlab.ui.control.Label
        BeamLengthinSlider             matlab.ui.control.Slider
        BeamLengthinSliderLabel        matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        BeamHeight = 12; %Height of beam
        BeamWidth = 12;  %width of beam
        FlangeThickness = 2;
        WebThickness = 2;
        BeamLength = 100;
        LoadLocation = 0;
        Load = 0;
        
        
    end
    
    methods (Access = public)
        
        function results = Update(app)
            
            %plots lines for web
            x1_height = [-app.WebThickness/2 -app.WebThickness/2];
            x2_height = [app.WebThickness/2 app.WebThickness/2];
            y1_height = [(-app.BeamHeight/2 + app.FlangeThickness/2), (app.BeamHeight/2 - app.FlangeThickness/2)];
            
            
            %plots lines for flange
            x1_width = [-app.BeamWidth/2 app.BeamWidth/2];
            y1_width = [app.BeamHeight/2 app.BeamHeight/2];
            y2_width = [-app.BeamHeight/2 -app.BeamHeight/2];
            
            x2_width = [-app.BeamWidth/2 -app.WebThickness/2];
            y3_width = [(app.BeamHeight/2 - app.FlangeThickness/2) (app.BeamHeight/2 - app.FlangeThickness/2)];
            x3_width = [app.WebThickness/2 app.BeamWidth/2];
            
            x4_width = [-app.BeamWidth/2 -app.BeamWidth/2];
            y4_width = [app.BeamHeight/2 (app.BeamHeight/2 - app.FlangeThickness/2)];
            
            num = 5000;
            x1_beam = linspace(-app.BeamLength/2,app.BeamLength/2,num);
            y1_beam = zeros(1,num) + app.BeamHeight/2;
            y2_beam = zeros(1,num) + app.BeamHeight/2 - app.FlangeThickness/2;
            
            x2_beam = [app.BeamLength/2 app.BeamLength/2];
            y3_beam = [app.BeamHeight/2 app.BeamHeight/2-app.FlangeThickness/2];
            y4_beam = [app.BeamHeight/2 -app.BeamHeight/2];
            
            %for plotting load arrow
            x1_arrow = [app.LoadLocation app.LoadLocation];
            y1_arrow = [app.BeamHeight/2 1.5*app.BeamHeight];
            x2_arrow = [app.LoadLocation-20 app.LoadLocation];
            x3_arrow = [app.LoadLocation+20 app.LoadLocation];
            y2_arrow = [(app.BeamHeight/2)*1.5 app.BeamHeight/2];
     
            
            %clear plot
            plot(app.UIAxes_2,0,0);
            
            %hold on
            hold(app.UIAxes_2,'on');
            
            %for plotting beam cross section
            plot(app.UIAxes_2,x1_height,y1_height,'k',x2_height,y1_height,...
                'k',x1_width,y1_width,'k',x1_width,y2_width,'k',x2_width,y3_width,'k'...
                ,x3_width,y3_width,'k',x2_width,-y3_width,'k'...
                ,x3_width,-y3_width,'k',x4_width,y4_width,'k',-x4_width,y4_width,'k'...
                ,x4_width,-y4_width,'k',-x4_width,-y4_width,'k'...
                );
            
            %hold off
            hold(app.UIAxes_2,'off')
            
            arrowwidth = 7*(app.Load/10000) + 1;
            %for plotting beam displacement
            
            %clear plot
            plot(app.UIAxes,0,0);
            
            %hold on
            hold(app.UIAxes,'on');
            
            %plot beam data
            plot(app.UIAxes,x1_beam,y1_beam,'k',x1_beam,-y1_beam,'k',x1_beam,...
                -y2_beam,'k',x1_beam,y2_beam,'k',x2_beam,y3_beam,'k',-x2_beam,...
                y3_beam,'k',-x2_beam,-y3_beam,'k',x2_beam,-y3_beam,'k',...
                x2_beam,y4_beam,'k',-x2_beam,y4_beam,'k');
            
            %plot load arrow
            plot(app.UIAxes,x3_arrow,y2_arrow,'b','LineWidth',arrowwidth);
            plot(app.UIAxes,x1_arrow,y1_arrow,'b','LineWidth',arrowwidth);
            plot(app.UIAxes,x2_arrow,y2_arrow,'b','LineWidth',arrowwidth);
            
            %hold off
            hold(app.UIAxes,'off');
            
            weblim = app.BeamWidth;
            app.WebThicknessinSlider.Limits(2) = weblim;
            
            flangelim = app.BeamHeight;
            app.FlangeThicknessinSlider.Limits(2) = flangelim;
            
            if(app.BeamLength/2 < .6*app.BeamLength/2)
                loadlim = app.BeamLength/2;
                app.LoadLocationinSlider.Limits(1) = -loadlim;
                app.LoadLocationinSlider.Limits(2) = loadlim;
            else
                app.LoadLocationinSlider.Limits(1) = -.6*app.BeamLength/2;
                app.LoadLocationinSlider.Limits(2) = .6*app.BeamLength/2;
            end
            
            % calculate moment of inertia and display it in the moment of
            % inertia field
            app.MomentofInertiaEditField.Value = 1/12 * app.BeamWidth*app.BeamHeight^3 -...
                                    2 * (1/12 * (app.BeamWidth - 2 * app.WebThickness)...
                                    * (app.BeamHeight - 2 * app.FlangeThickness)^3);
            
            results = 1;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)

        end

        % Value changing function: BeamHeightinSlider
        function BeamHeightinSliderValueChanging(app, event)
            app.BeamHeight = event.Value;
           
            Update(app);
            
        end

        % Value changing function: BeamWidthinSlider
        function BeamWidthinSliderValueChanging(app, event)
            app.BeamWidth = event.Value;
             
            Update(app);
             
        end

        % Value changing function: WebThicknessinSlider
        function WebThicknessinSliderValueChanging(app, event)
            app.WebThickness = event.Value;
            
            Update(app);
            
        end

        % Value changing function: FlangeThicknessinSlider
        function FlangeThicknessinSliderValueChanging(app, event)
            app.FlangeThickness = event.Value;
                       
            Update(app);
              
        end

        % Callback function
        function MomentofInertiaEditFieldValueChanged(app, event)
            app.MomentofInertiaEditField.Value = app.BeamWidth;
            
        end

        % Value changing function: BeamLengthinSlider
        function BeamLengthinSliderValueChanging(app, event)
            app.BeamLength = event.Value;
            
            Update(app);
            
        end

        % Value changing function: LoadLocationinSlider
        function LoadLocationinSliderValueChanging(app, event)
            app.LoadLocation = event.Value;
            Update(app);
        end

        % Value changing function: LoadpoundsSlider
        function LoadpoundsSliderValueChanging(app, event)
            app.Load = event.Value;
            Update(app);
        end

        % Value changed function: MomentofInertiaEditField
        function MomentofInertiaEditFieldValueChanged2(app, event)
            value = app.MomentofInertiaEditField.Value;
            Update(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Beam Displacment')
            xlabel(app.UIAxes, 'in')
            ylabel(app.UIAxes, 'in')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-200 200];
            app.UIAxes.YLim = [-30 30];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Beam Cross Section')
            xlabel(app.UIAxes_2, 'in')
            ylabel(app.UIAxes_2, 'in')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [-15 15];
            app.UIAxes_2.YLim = [-15 15];
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.BeamLengthinSliderLabel = uilabel(app.UIFigure);
            app.BeamLengthinSliderLabel.Text = 'Beam Length (in)';
            app.BeamLengthinSlider = uislider(app.UIFigure);
            app.BeamLengthinSlider.Limits = [0 360];
            app.BeamLengthinSlider.ValueChangingFcn = createCallbackFcn(app, @BeamLengthinSliderValueChanging, true);
            app.BeamLengthinSlider.Value = 100;
            app.BeamHeightinSliderLabel = uilabel(app.UIFigure);
            app.BeamHeightinSliderLabel.Text = 'Beam Height (in)';
            app.BeamHeightinSlider = uislider(app.UIFigure);
            app.BeamHeightinSlider.Limits = [0 18];
            app.BeamHeightinSlider.ValueChangingFcn = createCallbackFcn(app, @BeamHeightinSliderValueChanging, true);
            app.BeamHeightinSlider.Value = 12;
            app.BeamWidthinSliderLabel = uilabel(app.UIFigure);
            app.BeamWidthinSliderLabel.Text = 'Beam Width(in)';
            app.BeamWidthinSlider = uislider(app.UIFigure);
            app.BeamWidthinSlider.Limits = [0 24];
            app.BeamWidthinSlider.ValueChangingFcn = createCallbackFcn(app, @BeamWidthinSliderValueChanging, true);
            app.BeamWidthinSlider.Value = 12;
            app.WebThicknessinSliderLabel = uilabel(app.UIFigure);
            app.WebThicknessinSliderLabel.Text = 'Web Thickness (in)';
            app.WebThicknessinSlider = uislider(app.UIFigure);
            app.WebThicknessinSlider.Limits = [0 12];
            app.WebThicknessinSlider.ValueChangingFcn = createCallbackFcn(app, @WebThicknessinSliderValueChanging, true);
            app.WebThicknessinSlider.Value = 2;
            app.FlangeThicknessinSliderLabel = uilabel(app.UIFigure);
            app.FlangeThicknessinSliderLabel.Text = 'Flange Thickness (in)';
            app.FlangeThicknessinSlider = uislider(app.UIFigure);
            app.FlangeThicknessinSlider.Limits = [0 24];
            app.FlangeThicknessinSlider.ValueChangingFcn = createCallbackFcn(app, @FlangeThicknessinSliderValueChanging, true);
            app.FlangeThicknessinSlider.Value = 2;
            app.LoadpoundsSliderLabel = uilabel(app.UIFigure);
            app.LoadpoundsSliderLabel.Text = 'Load (pounds)';
            app.LoadpoundsSlider = uislider(app.UIFigure);
            app.LoadpoundsSlider.Limits = [0 10000];
            app.LoadpoundsSlider.MajorTicks = [0 2500 5000 7500 10000];
            app.LoadpoundsSlider.MajorTickLabels = {'0', '2500', '5000', '7500', '10000'};
            app.LoadpoundsSlider.ValueChangingFcn = createCallbackFcn(app, @LoadpoundsSliderValueChanging, true);
            app.LoadLocationinSliderLabel = uilabel(app.UIFigure);
            app.LoadLocationinSliderLabel.Text = 'Load Location (in)';
            app.LoadLocationinSlider = uislider(app.UIFigure);
            app.LoadLocationinSlider.Limits = [-140 140];
            app.LoadLocationinSlider.ValueChangingFcn = createCallbackFcn(app, @LoadLocationinSliderValueChanging, true);
            app.MomentofInertiaEditFieldLabel = uilabel(app.UIFigure);
            app.MomentofInertiaEditFieldLabel.Text = 'Moment of Inertia';
            app.MomentofInertiaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MomentofInertiaEditField.ValueChangedFcn = createCallbackFcn(app, @MomentofInertiaEditFieldValueChanged2, true);
            app.MomentofInertiaEditFieldLabel_2 = uilabel(app.UIFigure);
            app.MomentofInertiaEditFieldLabel_2.Text = 'in^4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeamApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end